classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RecognizeButton                matlab.ui.control.Button
        PreviewButton                  matlab.ui.control.Button
        ClickifyouwanttoseepreviewfromKinectLabel  matlab.ui.control.Label
        ClicktorecognizefruitLabel     matlab.ui.control.Label
        RecognizedfruitEditFieldLabel  matlab.ui.control.Label
        RecognizedfruitEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        colorDevice;
        depthDevice;
        net;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.colorDevice = imaq.VideoDevice('kinect',1);
            app.depthDevice = imaq.VideoDevice('kinect',2);
            app.colorDevice();
            app.depthDevice();
            app.net = importKerasNetwork('model.h5');
%             %otwiera w starym oknie, ale kilkusekundowe opóźnienie i inny
%             %toolbox, wymaga Axes
%             app.colorDevice = webcam('Kinect V2 Video Sensor');
%             frame = snapshot(app.colorDevice);
%             im = image(app.UIAxes, zeros(size(frame),    'uint8'));
%             axis(app.UIAxes,    'image'    );
%             preview(app.colorDevice,im);
            
%             %otwiera w starym oknie, ale kilkusekundowe opóźnienie,
%             wymaga Axes
%             app.colorDevice = imaq.VideoDevice('kinect',1);
%             pv = preview(app.colorDevice);    %handle to an image
%             pvparent = get(pv, 'Parent');   %object that holds the preview
%             pvfig = ancestor(pvparent, 'figure');   %the figure that contains the image
%             set(pv, 'Parent', app.UIAxes);     %move the preview to the desired axes
%             set(app.UIAxes, 'Visible', 'off', 'YDir', 'reverse', 'XDir', 'reverse');  %where it will show up upside-down and backwards
%             set(pvfig, 'Visible', 'off');  %set the preview figure invisible
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            clearvars;
            close all;
            clc;


        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            %otwiera w nowym oknie, ale bez opóźnień
            preview(app.colorDevice);
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            colorImage = app.colorDevice();
            depthImage = app.depthDevice();
            
            vals = procesData(colorImage,depthImage,app.depthDevice);
            
            label = classify(app.net,vals);
            app.RecognizedfruitEditField.Value = string(label);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RecognizeButton = uibutton(app.UIFigure, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize ';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ClickifyouwanttoseepreviewfromKinectLabel = uilabel(app.UIFigure);
            app.ClickifyouwanttoseepreviewfromKinectLabel.Text = 'Click if you want to see preview from Kinect.';
            app.ClicktorecognizefruitLabel = uilabel(app.UIFigure);
            app.ClicktorecognizefruitLabel.Text = 'Click to recognize fruit.';
            app.RecognizedfruitEditFieldLabel = uilabel(app.UIFigure);
            app.RecognizedfruitEditFieldLabel.Text = 'Recognized fruit:';
            app.RecognizedfruitEditField = uieditfield(app.UIFigure, 'text');
            app.RecognizedfruitEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end